classdef launch_GUI_instrument < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EquipmentInterfaceUIFigure  matlab.ui.Figure
        PSU1                        matlab.ui.container.Panel
        PSU_LedCH1                  matlab.ui.control.Lamp
        PSU_LedCH2                  matlab.ui.control.Lamp
        PSU_LedCH3                  matlab.ui.control.Lamp
        PSU_LedCH4                  matlab.ui.control.Lamp
        PSU_ButtonCH1               matlab.ui.control.Button
        PSU_ButtonCH2               matlab.ui.control.Button
        PSU_ButtonCH3               matlab.ui.control.Button
        PSU_ButtonCH4               matlab.ui.control.Button
        Voltage1Label               matlab.ui.control.Label
        PSU_Voltage1                matlab.ui.control.Spinner
        Current1Label               matlab.ui.control.Label
        PSU_Current1                matlab.ui.control.Spinner
        PSU_Voltage2                matlab.ui.control.Spinner
        PSU_Current2                matlab.ui.control.Spinner
        PSU_Voltage3                matlab.ui.control.Spinner
        PSU_Current3                matlab.ui.control.Spinner
        PSU_Voltage4                matlab.ui.control.Spinner
        PSU_Current4                matlab.ui.control.Spinner
        PSU_ButtonOutput_psu1       matlab.ui.control.Button
        PSU_LedOutput_psu1          matlab.ui.control.Lamp
        VIN_NS                      matlab.ui.container.Panel
        VIN_Set_output              matlab.ui.control.Button
        VIN_NS_SamplingLabel        matlab.ui.control.Label
        VIN_NS_Sampling             matlab.ui.control.DropDown
        VIN_NS_ModeLabel            matlab.ui.control.Label
        VIN_NS_Mode                 matlab.ui.control.DropDown
        VIN_NS_OutputLabel          matlab.ui.control.Label
        VIN_NS_Output               matlab.ui.control.DropDown
        ImpedanceLabel              matlab.ui.control.Label
        VIN_NS_Impedence            matlab.ui.control.DropDown
        VIN_NS_panel                matlab.ui.container.Panel
        VIN_NS_FrequencyLabel       matlab.ui.control.Label
        VIN_NS_Frequency            matlab.ui.control.Spinner
        VIN_NS_AmplitudeLabel       matlab.ui.control.Label
        VIN_NS_Amplitude            matlab.ui.control.Spinner
        VIN_NS_OffsetLabel          matlab.ui.control.Label
        VIN_NS_Offset               matlab.ui.control.Spinner
        VIN_Set                     matlab.ui.control.Button
        InstrumentPanel             matlab.ui.container.Panel
        checkPSU1Label              matlab.ui.control.Label
        checkPSU1                   matlab.ui.control.EditField
        checkPSU2Label              matlab.ui.control.Label
        checkPSU2                   matlab.ui.control.EditField
        checkVINLabel               matlab.ui.control.Label
        checkVIN                    matlab.ui.control.EditField
        serialPSU1                  matlab.ui.control.DropDown
        serialPSU2                  matlab.ui.control.DropDown
        connect                     matlab.ui.control.Button
        CheckBox_PSU1               matlab.ui.control.CheckBox
        CheckBox_PSU2               matlab.ui.control.CheckBox
        CheckBox_VIN                matlab.ui.control.CheckBox
        CheckBox_SMU                matlab.ui.control.CheckBox
        usbSMU                      matlab.ui.control.Label
        checkSMULabel               matlab.ui.control.Label
        checkSMU                    matlab.ui.control.EditField
        CheckBox_VIN_NS             matlab.ui.control.CheckBox
        serialVIN_NS                matlab.ui.control.DropDown
        usbCLK_2                    matlab.ui.control.Label
        VIN_NSLabel                 matlab.ui.control.Label
        checkVIN_NS                 matlab.ui.control.EditField
        SMU                         matlab.ui.container.Panel
        SMU_CH1                     matlab.ui.container.Panel
        SMU_CH1_ButtonOutput        matlab.ui.control.ToggleSwitch
        SMU_Voltage1Label           matlab.ui.control.Label
        SMU_Voltage1                matlab.ui.control.Spinner
        SMU_Current1Label           matlab.ui.control.Label
        SMU_Current1                matlab.ui.control.Spinner
        SMU_Store                   matlab.ui.control.Button
        SMU_Recall                  matlab.ui.control.Button
        SMU_CH2                     matlab.ui.container.Panel
        SMU_CH2_ButtonOutput        matlab.ui.control.ToggleSwitch
        SMU_Current2Label           matlab.ui.control.Label
        SMU_Current2                matlab.ui.control.Spinner
        SMU_Voltage2Label           matlab.ui.control.Label
        SMU_Voltage2                matlab.ui.control.Spinner
        SMU_SR_Channel              matlab.ui.control.DropDown
        VIN                         matlab.ui.container.Panel
        VIN_CH1                     matlab.ui.container.Panel
        VIN_CH1_WaveformLabel       matlab.ui.control.Label
        VIN_CH1_Waveform            matlab.ui.control.DropDown
        VIN_CH1_FrequencyLabel      matlab.ui.control.Label
        VIN_CH1_Frequency           matlab.ui.control.Spinner
        VIN_CH1_FrequencyUnit       matlab.ui.control.DropDown
        VIN_CH1_AmplitudeLabel      matlab.ui.control.Label
        VIN_CH1_Amplitude           matlab.ui.control.Spinner
        VIN_CH1_OffsetLabel         matlab.ui.control.Label
        VIN_CH1_Offset              matlab.ui.control.Spinner
        VIN_CH1_PhaseLabel          matlab.ui.control.Label
        VIN_CH1_Phase               matlab.ui.control.Spinner
        VIN_CH1_ButtonOutput        matlab.ui.control.ToggleSwitch
        VIN_AlignPhase              matlab.ui.control.Button
        VIN_Store                   matlab.ui.control.Button
        VIN_SR_Channel              matlab.ui.control.DropDown
        VIN_Recall                  matlab.ui.control.Button
        VIN_CH2                     matlab.ui.container.Panel
        VIN_CH1_Waveform_2Label     matlab.ui.control.Label
        VIN_CH2_Waveform            matlab.ui.control.DropDown
        VIN_CH1_Frequency_2Label    matlab.ui.control.Label
        VIN_CH2_Frequency           matlab.ui.control.Spinner
        VIN_CH2_FrequencyUnit       matlab.ui.control.DropDown
        VIN_CH1_Amplitude_2Label    matlab.ui.control.Label
        VIN_CH2_Amplitude           matlab.ui.control.Spinner
        VIN_CH1_Offset_2Label       matlab.ui.control.Label
        VIN_CH2_Offset              matlab.ui.control.Spinner
        VIN_CH1_Phase_2Label        matlab.ui.control.Label
        VIN_CH2_Phase               matlab.ui.control.Spinner
        VIN_CH2_ButtonOutput        matlab.ui.control.ToggleSwitch
        logo                        matlab.ui.control.Button
        PSU2                        matlab.ui.container.Panel
        PSU_LedCH5                  matlab.ui.control.Lamp
        PSU_LedCH6                  matlab.ui.control.Lamp
        PSU_LedCH7                  matlab.ui.control.Lamp
        PSU_LedCH8                  matlab.ui.control.Lamp
        PSU_ButtonCH5               matlab.ui.control.Button
        PSU_ButtonCH6               matlab.ui.control.Button
        PSU_ButtonCH7               matlab.ui.control.Button
        PSU_ButtonCH8               matlab.ui.control.Button
        PSU_Voltage1_2Label         matlab.ui.control.Label
        PSU_Voltage5                matlab.ui.control.Spinner
        PSU_Current1_2Label         matlab.ui.control.Label
        PSU_Current5                matlab.ui.control.Spinner
        PSU_Voltage6                matlab.ui.control.Spinner
        PSU_Current6                matlab.ui.control.Spinner
        PSU_Voltage7                matlab.ui.control.Spinner
        PSU_Current7                matlab.ui.control.Spinner
        PSU_Voltage8                matlab.ui.control.Spinner
        PSU_Current8                matlab.ui.control.Spinner
        PSU_ButtonOutput_psu2       matlab.ui.control.Button
        PSU_LedOutput_psu2          matlab.ui.control.Lamp
    end


    properties (Access = public)
        psu1; 
        psu2;
        clk;
        vin;
        vin_ns;
        smu;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.PSU1.Visible = 'off';
           app.PSU2.Visible = 'off';
            %            app.CLK.Visible = 'off';
           app.VIN.Visible = 'off';
           app.VIN_NS.Visible = 'off';
           app.SMU.Visible = 'off';
        end

        % Value changing function: PSU_Voltage1, PSU_Voltage5
        function PSU_Voltage1ValueChanging(app, event)
            psu_vi_tag = [1 1 1];
            PSU_VI_setting;
        end

        % Value changing function: PSU_Current1, PSU_Current5
        function PSU_Current1ValueChanging(app, event)
            psu_vi_tag = [1 1 2];
            PSU_VI_setting;
        end

        % Value changing function: PSU_Voltage2
        function PSU_Voltage2ValueChanging(app, event)
            psu_vi_tag = [1 2 1];
            PSU_VI_setting;           
        end

        % Value changing function: PSU_Current2
        function PSU_Current2ValueChanging(app, event)
            psu_vi_tag = [1 2 2];
            PSU_VI_setting;
        end

        % Value changing function: PSU_Voltage3
        function PSU_Voltage3ValueChanging(app, event)
            psu_vi_tag = [1 3 1];
            PSU_VI_setting;
        end

        % Value changing function: PSU_Current3
        function PSU_Current3ValueChanging(app, event)
            psu_vi_tag = [1 3 2];
            PSU_VI_setting;
        end

        % Value changing function: PSU_Voltage4
        function PSU_Voltage4ValueChanging(app, event)
            psu_vi_tag = [1 4 1];
            PSU_VI_setting;
        end

        % Value changing function: PSU_Current4
        function PSU_Current4ValueChanging(app, event)
            psu_vi_tag = [1 4 2];
            PSU_VI_setting;
        end

        % Button pushed function: PSU_ButtonCH1
        function PSU_ButtonCH1ButtonPushed(app, event)
            psu_channel_tag = [1 1];
            PSU_channel_power;
        end

        % Button pushed function: PSU_ButtonCH2
        function PSU_ButtonCH2ButtonPushed(app, event)
            psu_channel_tag = [1 2];
            PSU_channel_power;
        end

        % Button pushed function: PSU_ButtonCH3
        function PSU_ButtonCH3ButtonPushed(app, event)
            psu_channel_tag = [1 3];
            PSU_channel_power;
        end

        % Button pushed function: PSU_ButtonCH4
        function PSU_ButtonCH4ButtonPushed(app, event)
            psu_channel_tag = [1 4];
            PSU_channel_power;
        end

        % Button pushed function: PSU_ButtonOutput_psu1
        function PSU_ButtonOutput1ButtonPushed(app, event)
            psu_power_tag = 1;
            PSU_output_power;
        end

        % Value changing function: PSU_Voltage5
        function PSU_Voltage5ValueChanging(app, event)
            psu_vi_tag = [2 1 1];
            PSU_VI_setting;
        end

        % Value changing function: PSU_Current5
        function PSU_Current5ValueChanging(app, event)
            psu_vi_tag = [2 1 2];
            PSU_VI_setting;
        end

        % Value changing function: PSU_Voltage6
        function PSU_Voltage6ValueChanging(app, event)
            psu_vi_tag = [2 2 1];
            PSU_VI_setting;
        end

        % Value changing function: PSU_Current6
        function PSU_Current6ValueChanging(app, event)
            psu_vi_tag = [2 2 2];
            PSU_VI_setting;
        end

        % Value changing function: PSU_Voltage7
        function PSU_Voltage7ValueChanging(app, event)
            psu_vi_tag = [2 3 1];
            PSU_VI_setting;
        end

        % Value changing function: PSU_Current7
        function PSU_Current7ValueChanging(app, event)
            psu_vi_tag = [2 3 2];
            PSU_VI_setting;
        end

        % Value changing function: PSU_Voltage8
        function PSU_Voltage8ValueChanging(app, event)
            psu_vi_tag = [2 4 1];
            PSU_VI_setting;
        end

        % Value changing function: PSU_Current8
        function PSU_Current8ValueChanging(app, event)
            psu_vi_tag = [2 4 2];
            PSU_VI_setting;
        end

        % Button pushed function: PSU_ButtonCH5
        function PSU_ButtonCH5ButtonPushed(app, event)
            psu_channel_tag = [2 1];
            PSU_channel_power;
        end

        % Button pushed function: PSU_ButtonCH6
        function PSU_ButtonCH6ButtonPushed(app, event)
            psu_channel_tag = [2 2];
            PSU_channel_power;
        end

        % Button pushed function: PSU_ButtonCH7
        function PSU_ButtonCH7ButtonPushed(app, event)
            psu_channel_tag = [2 3];
            PSU_channel_power;
        end

        % Button pushed function: PSU_ButtonCH8
        function PSU_ButtonCH8ButtonPushed(app, event)
            psu_channel_tag = [2 4];
            PSU_channel_power;
        end

        % Button pushed function: PSU_ButtonOutput_psu2
        function PSU_ButtonOutput2ButtonPushed(app, event)
            psu_power_tag = 2;
            PSU_output_power;
        end

        % Button pushed function: connect
        function connectButtonPushed(app, event)
            instruments_connection;
        end

        % Value changing function: SMU_Voltage1
        function SMU_Voltage1ValueChanging(app, event)
            smu_tag = [1,1];
            SMU_VI_setting; 
        end

        % Value changing function: SMU_Current1
        function SMU_Current1ValueChanging(app, event)
            smu_tag = [1,2];
            SMU_VI_setting; 
        end

        % Value changing function: SMU_Voltage2
        function SMU_Voltage2ValueChanging(app, event)
            smu_tag = [2,1];
            SMU_VI_setting; 
        end

        % Value changing function: SMU_Current2
        function SMU_Current2ValueChanging(app, event)
            smu_tag = [2,2];
            SMU_VI_setting; 
        end

        % Value changed function: SMU_CH1_ButtonOutput
        function SMU_CH1_ButtonOutputValueChanged(app, event)
            smu_tag = '1';
            SMU_output_power;         
        end

        % Value changed function: SMU_CH2_ButtonOutput
        function SMU_CH2_ButtonOutputValueChanged(app, event)
            smu_tag = '2';
            SMU_output_power; 
        end

        % Button pushed function: SMU_Store
        function SMU_StoreButtonPushed(app, event)
            smu_tag = 'save';
            SMU_storage;
        end

        % Button pushed function: SMU_Recall
        function SMU_RecallButtonPushed(app, event)
            smu_tag = 'recall';
            SMU_storage;
        end

        % Button pushed function: logo
        function logoButtonPushed(app, event)
            web('http://www.mcci.ie/');
        end

        % Button pushed function: VIN_Set_output
        function VIN_Set_outputButtonPushed(app, event)
            VIN_NS_set_output;
        end

        % Button pushed function: VIN_Set
        function VIN_SetButtonPushed(app, event)
            VIN_NS_set_channel;
        end

        % Callback function
        function VIN_NS_SamplingValueChanged(app, event)
            value = app.VIN_NS_Sampling.Value;
            if strcmp(value,'512 kHz')
                app.VIN_NS_Frequency.Limits = [0 200];
            else
                app.VIN_NS_Frequency.Limits = [0 57.6];
            end
        end

        % Value changed function: VIN_CH1_Waveform
        function VIN_CH1_WaveformValueChanged(app, event)
            [dummy,index] = ismember(app.VIN_CH1_FrequencyUnit.Value,app.VIN_CH1_FrequencyUnit.Items);
            vin_tag = [1 index];
            VIN_waveform;
            VIN_frequency_unit;
        end

        % Value changed function: VIN_CH1_Frequency
        function VIN_CH1_FrequencyValueChanging(app, event)
            vin_tag = 1;
            value_freq = event.Value;
            VIN_frequency;
        end

        % Value changed function: VIN_CH1_FrequencyUnit
        function VIN_CH1_FrequencyUnitValueChanged(app, event)
            [dummy,index] = ismember(app.VIN_CH1_FrequencyUnit.Value,app.VIN_CH1_FrequencyUnit.Items);
            vin_tag = [1 index];
            VIN_frequency_unit;
            VIN_frequency;
        end

        % Value changing function: VIN_CH1_Amplitude
        function VIN_CH1_AmplitudeValueChanging(app, event)
            vin_tag = [1 2];
            VIN_settings;
        end

        % Value changing function: VIN_CH1_Offset
        function VIN_CH1_OffsetValueChanging(app, event)
            vin_tag = [1 3];
            VIN_settings;
        end

        % Value changing function: VIN_CH1_Phase
        function VIN_CH1_PhaseValueChanging(app, event)
            vin_tag = [1 4];
            VIN_settings;
        end

        % Value changed function: VIN_CH1_ButtonOutput
        function VIN_CH1_ButtonOutputValueChanged(app, event)
            vin_tag = [1 1];
            VIN_settings;
        end

        % Value changed function: VIN_CH2_Waveform
        function VIN_CH2_WaveformValueChanged(app, event)
            [dummy,index] = ismember(app.VIN_CH2_FrequencyUnit.Value,app.VIN_CH2_FrequencyUnit.Items);
            vin_tag = [2 index];
            VIN_waveform;
            VIN_frequency_unit;
        end

        % Value changed function: VIN_CH2_Frequency
        function VIN_CH2_FrequencyValueChanging(app, event)
            vin_tag = 2;
            value_freq = event.Value;
            VIN_frequency;
        end

        % Value changed function: VIN_CH2_FrequencyUnit
        function VIN_CH2_FrequencyUnitValueChanged(app, event)
            [dummy,index] = ismember(app.VIN_CH2_FrequencyUnit.Value,app.VIN_CH2_FrequencyUnit.Items);
            vin_tag = [2 index];
            VIN_frequency_unit;
            VIN_frequency;
        end

        % Value changing function: VIN_CH2_Amplitude
        function VIN_CH2_AmplitudeValueChanging(app, event)
            vin_tag = [2 2];
            VIN_settings;
        end

        % Value changing function: VIN_CH2_Offset
        function VIN_CH2_OffsetValueChanging(app, event)
            vin_tag = [2 3];
            VIN_settings;
        end

        % Value changing function: VIN_CH2_Phase
        function VIN_CH2_PhaseValueChanging(app, event)
            vin_tag = [2 4];
            VIN_settings;
        end

        % Value changed function: VIN_CH2_ButtonOutput
        function VIN_CH2_ButtonOutputValueChanged(app, event)
            vin_tag = [2 1];
            VIN_settings;
        end

        % Button pushed function: VIN_AlignPhase
        function VIN_AlignPhaseButtonPushed(app, event)
            vin_tag = [1 5];
            VIN_settings;
        end

        % Button pushed function: VIN_Store
        function VIN_StoreButtonPushed(app, event)
            vin_storage_tag = 'save';
            VIN_storage;
        end

        % Button pushed function: VIN_Recall
        function VIN_RecallButtonPushed(app, event)
            vin_storage_tag = 'recall';
            VIN_storage;
        end

        % Close request function: EquipmentInterfaceUIFigure
        function close_instruments(app, event)
            EQUIP_LIST = {app.psu1 app.psu2 app.smu app.vin app.vin_ns};
            EQUIP_OPEN = {app.PSU1.Visible app.PSU2.Visible app.SMU.Visible app.VIN.Visible app.VIN_NS.Visible};
            
            for i=1:5
                if strcmp(EQUIP_OPEN{i},'on')
                    fclose(EQUIP_LIST{i});
                    delete(EQUIP_LIST{i});
                end
            end
            delete(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EquipmentInterfaceUIFigure = uifigure;
            app.EquipmentInterfaceUIFigure.Name = 'Equipment Interface';
            app.EquipmentInterfaceUIFigure.CloseRequestFcn = createCallbackFcn(app, @close_instruments, true);
            setAutoResize(app, app.EquipmentInterfaceUIFigure, false)
            app.PSU1 = uipanel(app.EquipmentInterfaceUIFigure);
            app.PSU1.Title = '~~~~~~~~~~~~~~ PSU #1 ~~~~~~~~~~~~~';
            app.PSU_LedCH1 = uilamp(app.PSU1);
            app.PSU_LedCH1.Enable = 'off';
            app.PSU_LedCH2 = uilamp(app.PSU1);
            app.PSU_LedCH2.Enable = 'off';
            app.PSU_LedCH3 = uilamp(app.PSU1);
            app.PSU_LedCH3.Enable = 'off';
            app.PSU_LedCH4 = uilamp(app.PSU1);
            app.PSU_LedCH4.Enable = 'off';
            app.PSU_ButtonCH1 = uibutton(app.PSU1, 'push');
            app.PSU_ButtonCH1.ButtonPushedFcn = createCallbackFcn(app, @PSU_ButtonCH1ButtonPushed, true);
            app.PSU_ButtonCH1.Text = 'CH1';
            app.PSU_ButtonCH2 = uibutton(app.PSU1, 'push');
            app.PSU_ButtonCH2.ButtonPushedFcn = createCallbackFcn(app, @PSU_ButtonCH2ButtonPushed, true);
            app.PSU_ButtonCH2.Text = 'CH2';
            app.PSU_ButtonCH3 = uibutton(app.PSU1, 'push');
            app.PSU_ButtonCH3.ButtonPushedFcn = createCallbackFcn(app, @PSU_ButtonCH3ButtonPushed, true);
            app.PSU_ButtonCH3.Text = 'CH3';
            app.PSU_ButtonCH4 = uibutton(app.PSU1, 'push');
            app.PSU_ButtonCH4.ButtonPushedFcn = createCallbackFcn(app, @PSU_ButtonCH4ButtonPushed, true);
            app.PSU_ButtonCH4.Text = 'CH4';
            app.Voltage1Label = uilabel(app.PSU1);
            app.Voltage1Label.Text = 'Voltage (V)';
            app.PSU_Voltage1 = uispinner(app.PSU1);
            app.PSU_Voltage1.Step = 0.1;
            app.PSU_Voltage1.ValueChangingFcn = createCallbackFcn(app, @PSU_Voltage1ValueChanging, true);
            app.PSU_Voltage1.Limits = [0 32];
            app.PSU_Voltage1.ValueDisplayFormat = '%.3f';
            app.PSU_Voltage1.Value = 1;
            app.Current1Label = uilabel(app.PSU1);
            app.Current1Label.Text = 'Current (mA)';
            app.PSU_Current1 = uispinner(app.PSU1);
            app.PSU_Current1.ValueChangingFcn = createCallbackFcn(app, @PSU_Current1ValueChanging, true);
            app.PSU_Current1.Limits = [0 10000];
            app.PSU_Current1.ValueDisplayFormat = '%.1f';
            app.PSU_Current1.Value = 1;
            app.PSU_Voltage2 = uispinner(app.PSU1);
            app.PSU_Voltage2.Step = 0.1;
            app.PSU_Voltage2.ValueChangingFcn = createCallbackFcn(app, @PSU_Voltage2ValueChanging, true);
            app.PSU_Voltage2.Limits = [0 32];
            app.PSU_Voltage2.ValueDisplayFormat = '%.3f';
            app.PSU_Voltage2.Value = 1;
            app.PSU_Current2 = uispinner(app.PSU1);
            app.PSU_Current2.ValueChangingFcn = createCallbackFcn(app, @PSU_Current2ValueChanging, true);
            app.PSU_Current2.Limits = [0 10000];
            app.PSU_Current2.ValueDisplayFormat = '%.1f';
            app.PSU_Current2.Value = 1;
            app.PSU_Voltage3 = uispinner(app.PSU1);
            app.PSU_Voltage3.Step = 0.1;
            app.PSU_Voltage3.ValueChangingFcn = createCallbackFcn(app, @PSU_Voltage3ValueChanging, true);
            app.PSU_Voltage3.Limits = [0 32];
            app.PSU_Voltage3.ValueDisplayFormat = '%.3f';
            app.PSU_Voltage3.Value = 1;
            app.PSU_Current3 = uispinner(app.PSU1);
            app.PSU_Current3.ValueChangingFcn = createCallbackFcn(app, @PSU_Current3ValueChanging, true);
            app.PSU_Current3.Limits = [0 10000];
            app.PSU_Current3.ValueDisplayFormat = '%.1f';
            app.PSU_Current3.Value = 1;
            app.PSU_Voltage4 = uispinner(app.PSU1);
            app.PSU_Voltage4.Step = 0.1;
            app.PSU_Voltage4.ValueChangingFcn = createCallbackFcn(app, @PSU_Voltage4ValueChanging, true);
            app.PSU_Voltage4.Limits = [0 32];
            app.PSU_Voltage4.ValueDisplayFormat = '%.3f';
            app.PSU_Voltage4.Value = 1;
            app.PSU_Current4 = uispinner(app.PSU1);
            app.PSU_Current4.ValueChangingFcn = createCallbackFcn(app, @PSU_Current4ValueChanging, true);
            app.PSU_Current4.Limits = [0 10000];
            app.PSU_Current4.ValueDisplayFormat = '%.1f';
            app.PSU_Current4.Value = 1;
            app.PSU_ButtonOutput_psu1 = uibutton(app.PSU1, 'push');
            app.PSU_ButtonOutput_psu1.ButtonPushedFcn = createCallbackFcn(app, @PSU_ButtonOutput1ButtonPushed, true);
            app.PSU_ButtonOutput_psu1.Text = 'OUTPUT';
            app.PSU_LedOutput_psu1 = uilamp(app.PSU1);
            app.PSU_LedOutput_psu1.Enable = 'off';
            app.VIN_NS = uipanel(app.EquipmentInterfaceUIFigure);
            app.VIN_NS.Title = '~~~~~~~~~~~~~~~~~~~~~~~~~~~ VIN NS ~~~~~~~~~~~~~~~~~~~~~~~~~~~~';
            app.VIN_Set_output = uibutton(app.VIN_NS, 'push');
            app.VIN_Set_output.ButtonPushedFcn = createCallbackFcn(app, @VIN_Set_outputButtonPushed, true);
            app.VIN_Set_output.Text = 'SET';
            app.VIN_NS_SamplingLabel = uilabel(app.VIN_NS);
            app.VIN_NS_SamplingLabel.Text = 'Sampling';
            app.VIN_NS_Sampling = uidropdown(app.VIN_NS);
            app.VIN_NS_Sampling.Items = {'512 kHz', '128 kHz'};
            app.VIN_NS_Sampling.Value = '128 kHz';
            app.VIN_NS_ModeLabel = uilabel(app.VIN_NS);
            app.VIN_NS_ModeLabel.Text = 'Mode';
            app.VIN_NS_Mode = uidropdown(app.VIN_NS);
            app.VIN_NS_Mode.Items = {'Mono', 'Stereo'};
            app.VIN_NS_Mode.Value = 'Stereo';
            app.VIN_NS_OutputLabel = uilabel(app.VIN_NS);
            app.VIN_NS_OutputLabel.Text = 'Output';
            app.VIN_NS_Output = uidropdown(app.VIN_NS);
            app.VIN_NS_Output.Items = {'Unbal GND', 'Unbal FLOAT'};
            app.VIN_NS_Output.Value = 'Unbal GND';
            app.ImpedanceLabel = uilabel(app.VIN_NS);
            app.ImpedanceLabel.Text = 'Impedance';
            app.VIN_NS_Impedence = uidropdown(app.VIN_NS);
            app.VIN_NS_Impedence.Items = {'25', '75', '600'};
            app.VIN_NS_Impedence.Value = '25';
            app.VIN_NS_panel = uipanel(app.VIN_NS);
            app.VIN_NS_FrequencyLabel = uilabel(app.VIN_NS_panel);
            app.VIN_NS_FrequencyLabel.Text = 'Frequency (kHz)';
            app.VIN_NS_Frequency = uispinner(app.VIN_NS_panel);
            app.VIN_NS_Frequency.Limits = [0 57.6];
            app.VIN_NS_Frequency.ValueDisplayFormat = '%.4f';
            app.VIN_NS_Frequency.Value = 1;
            app.VIN_NS_AmplitudeLabel = uilabel(app.VIN_NS_panel);
            app.VIN_NS_AmplitudeLabel.Text = 'Amplitude (Vpp)';
            app.VIN_NS_Amplitude = uispinner(app.VIN_NS_panel);
            app.VIN_NS_Amplitude.Step = 0.1;
            app.VIN_NS_Amplitude.Limits = [0 40];
            app.VIN_NS_Amplitude.ValueDisplayFormat = '%.3f';
            app.VIN_NS_Amplitude.Value = 1;
            app.VIN_NS_OffsetLabel = uilabel(app.VIN_NS_panel);
            app.VIN_NS_OffsetLabel.Text = 'Offset (Vpp)';
            app.VIN_NS_Offset = uispinner(app.VIN_NS_panel);
            app.VIN_NS_Offset.Step = 0.1;
            app.VIN_NS_Offset.Limits = [0 40];
            app.VIN_NS_Offset.ValueDisplayFormat = '%.3f';
            app.VIN_NS_Offset.Value = 1;
            app.VIN_Set = uibutton(app.VIN_NS_panel, 'push');
            app.VIN_Set.ButtonPushedFcn = createCallbackFcn(app, @VIN_SetButtonPushed, true);
            app.VIN_Set.Text = 'SET';
            app.InstrumentPanel = uipanel(app.EquipmentInterfaceUIFigure);
            app.checkPSU1Label = uilabel(app.InstrumentPanel);
            app.checkPSU1Label.Text = 'PSU1';
            app.checkPSU1 = uieditfield(app.InstrumentPanel, 'text');
            app.checkPSU1.Editable = 'off';
            app.checkPSU2Label = uilabel(app.InstrumentPanel);
            app.checkPSU2Label.Text = 'PSU2';
            app.checkPSU2 = uieditfield(app.InstrumentPanel, 'text');
            app.checkPSU2.Editable = 'off';
            app.checkVINLabel = uilabel(app.InstrumentPanel);
            app.checkVINLabel.Text = 'VIN';
            app.checkVIN = uieditfield(app.InstrumentPanel, 'text');
            app.checkVIN.Editable = 'off';
            app.serialPSU1 = uidropdown(app.InstrumentPanel);
            app.serialPSU1.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13'};
            app.serialPSU1.Value = 'COM4';
            app.serialPSU2 = uidropdown(app.InstrumentPanel);
            app.serialPSU2.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13'};
            app.serialPSU2.Value = 'COM6';
            app.connect = uibutton(app.InstrumentPanel, 'push');
            app.connect.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connect.Text = {'C'; 'O'; 'N'; 'N'; 'E'; 'C'; 'T'};
            app.CheckBox_PSU1 = uicheckbox(app.InstrumentPanel);
            app.CheckBox_PSU1.Text = '';
            app.CheckBox_PSU2 = uicheckbox(app.InstrumentPanel);
            app.CheckBox_PSU2.Text = '';
            app.CheckBox_VIN = uicheckbox(app.InstrumentPanel);
            app.CheckBox_VIN.Text = '';
            app.CheckBox_SMU = uicheckbox(app.InstrumentPanel);
            app.CheckBox_SMU.Text = '';
            app.usbSMU = uilabel(app.InstrumentPanel);
            app.usbSMU.Text = 'USB';
            app.checkSMULabel = uilabel(app.InstrumentPanel);
            app.checkSMULabel.Text = 'SMU';
            app.checkSMU = uieditfield(app.InstrumentPanel, 'text');
            app.checkSMU.Editable = 'off';
            app.CheckBox_VIN_NS = uicheckbox(app.InstrumentPanel);
            app.CheckBox_VIN_NS.Text = '';
            app.serialVIN_NS = uidropdown(app.InstrumentPanel);
            app.serialVIN_NS.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13'};
            app.serialVIN_NS.Value = 'COM2';
            app.usbCLK_2 = uilabel(app.InstrumentPanel);
            app.usbCLK_2.Text = 'USB';
            app.VIN_NSLabel = uilabel(app.InstrumentPanel);
            app.VIN_NSLabel.Text = 'VIN_NS';
            app.checkVIN_NS = uieditfield(app.InstrumentPanel, 'text');
            app.checkVIN_NS.Editable = 'off';
            app.SMU = uipanel(app.EquipmentInterfaceUIFigure);
            app.SMU.Title = '~~~~~~~~~~~~~~~~~~~~~~~~~~~~ SMU ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~';
            app.SMU_CH1 = uipanel(app.SMU);
            app.SMU_CH1.Title = 'CH1';
            app.SMU_CH1_ButtonOutput = uiswitch(app.SMU_CH1, 'toggle');
            app.SMU_CH1_ButtonOutput.ValueChangedFcn = createCallbackFcn(app, @SMU_CH1_ButtonOutputValueChanged, true);
            app.SMU_Voltage1Label = uilabel(app.SMU_CH1);
            app.SMU_Voltage1Label.Text = 'Voltage (V)';
            app.SMU_Voltage1 = uispinner(app.SMU_CH1);
            app.SMU_Voltage1.Step = 0.1;
            app.SMU_Voltage1.ValueChangingFcn = createCallbackFcn(app, @SMU_Voltage1ValueChanging, true);
            app.SMU_Voltage1.Limits = [0 10];
            app.SMU_Voltage1.ValueDisplayFormat = '%11.6g';
            app.SMU_Voltage1.Value = 1;
            app.SMU_Current1Label = uilabel(app.SMU_CH1);
            app.SMU_Current1Label.Text = 'Current (mA)';
            app.SMU_Current1 = uispinner(app.SMU_CH1);
            app.SMU_Current1.ValueChangingFcn = createCallbackFcn(app, @SMU_Current1ValueChanging, true);
            app.SMU_Current1.Limits = [0 200];
            app.SMU_Current1.ValueDisplayFormat = '%11.5g';
            app.SMU_Current1.Value = 1;
            app.SMU_Store = uibutton(app.SMU, 'push');
            app.SMU_Store.ButtonPushedFcn = createCallbackFcn(app, @SMU_StoreButtonPushed, true);
            app.SMU_Store.Text = 'STORE';
            app.SMU_Recall = uibutton(app.SMU, 'push');
            app.SMU_Recall.ButtonPushedFcn = createCallbackFcn(app, @SMU_RecallButtonPushed, true);
            app.SMU_Recall.Text = 'RECALL';
            app.SMU_CH2 = uipanel(app.SMU);
            app.SMU_CH2.Title = 'CH2';
            app.SMU_CH2_ButtonOutput = uiswitch(app.SMU_CH2, 'toggle');
            app.SMU_CH2_ButtonOutput.ValueChangedFcn = createCallbackFcn(app, @SMU_CH2_ButtonOutputValueChanged, true);
            app.SMU_Current2Label = uilabel(app.SMU_CH2);
            app.SMU_Current2Label.Text = 'Current (mA)';
            app.SMU_Current2 = uispinner(app.SMU_CH2);
            app.SMU_Current2.ValueChangingFcn = createCallbackFcn(app, @SMU_Current2ValueChanging, true);
            app.SMU_Current2.Limits = [0 200];
            app.SMU_Current2.ValueDisplayFormat = '%11.5g';
            app.SMU_Current2.Value = 1;
            app.SMU_Voltage2Label = uilabel(app.SMU_CH2);
            app.SMU_Voltage2Label.Text = 'Voltage (V)';
            app.SMU_Voltage2 = uispinner(app.SMU_CH2);
            app.SMU_Voltage2.Step = 0.1;
            app.SMU_Voltage2.ValueChangingFcn = createCallbackFcn(app, @SMU_Voltage2ValueChanging, true);
            app.SMU_Voltage2.Limits = [0 10];
            app.SMU_Voltage2.ValueDisplayFormat = '%11.6g';
            app.SMU_Voltage2.Value = 1;
            app.SMU_SR_Channel = uidropdown(app.SMU);
            app.SMU_SR_Channel.Items = {'1', '2', '3', '4', '5'};
            app.SMU_SR_Channel.Value = '1';
            app.VIN = uipanel(app.EquipmentInterfaceUIFigure);
            app.VIN.Title = '~~~~~~~~~~~~~~~~~~~~~~~~~~~~~ VIN ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~';
            app.VIN_CH1 = uipanel(app.VIN);
            app.VIN_CH1_WaveformLabel = uilabel(app.VIN_CH1);
            app.VIN_CH1_WaveformLabel.Text = 'Waveform';
            app.VIN_CH1_Waveform = uidropdown(app.VIN_CH1);
            app.VIN_CH1_Waveform.Items = {'Sine', 'Square', 'Triangle', 'DC'};
            app.VIN_CH1_Waveform.ValueChangedFcn = createCallbackFcn(app, @VIN_CH1_WaveformValueChanged, true);
            app.VIN_CH1_Waveform.Value = 'Sine';
            app.VIN_CH1_FrequencyLabel = uilabel(app.VIN_CH1);
            app.VIN_CH1_FrequencyLabel.Text = 'Frequency';
            app.VIN_CH1_Frequency = uispinner(app.VIN_CH1);
            app.VIN_CH1_Frequency.ValueChangedFcn = createCallbackFcn(app, @VIN_CH1_FrequencyValueChanging, true);
            app.VIN_CH1_Frequency.Limits = [0 1000];
            app.VIN_CH1_Frequency.ValueDisplayFormat = '%11.8g';
            app.VIN_CH1_Frequency.Value = 1;
            app.VIN_CH1_FrequencyUnit = uidropdown(app.VIN_CH1);
            app.VIN_CH1_FrequencyUnit.Items = {'uHz', 'mHz', 'Hz', 'kHz', 'MHz'};
            app.VIN_CH1_FrequencyUnit.ValueChangedFcn = createCallbackFcn(app, @VIN_CH1_FrequencyUnitValueChanged, true);
            app.VIN_CH1_FrequencyUnit.Value = 'Hz';
            app.VIN_CH1_AmplitudeLabel = uilabel(app.VIN_CH1);
            app.VIN_CH1_AmplitudeLabel.Text = 'Amplitude (V)';
            app.VIN_CH1_Amplitude = uispinner(app.VIN_CH1);
            app.VIN_CH1_Amplitude.Step = 0.1;
            app.VIN_CH1_Amplitude.ValueChangingFcn = createCallbackFcn(app, @VIN_CH1_AmplitudeValueChanging, true);
            app.VIN_CH1_Amplitude.Limits = [0.001 10];
            app.VIN_CH1_Amplitude.ValueDisplayFormat = '%.3f';
            app.VIN_CH1_Amplitude.Value = 1;
            app.VIN_CH1_OffsetLabel = uilabel(app.VIN_CH1);
            app.VIN_CH1_OffsetLabel.Text = 'Offset (V)';
            app.VIN_CH1_Offset = uispinner(app.VIN_CH1);
            app.VIN_CH1_Offset.Step = 0.1;
            app.VIN_CH1_Offset.ValueChangingFcn = createCallbackFcn(app, @VIN_CH1_OffsetValueChanging, true);
            app.VIN_CH1_Offset.Limits = [-5 5];
            app.VIN_CH1_Offset.ValueDisplayFormat = '%.3f';
            app.VIN_CH1_PhaseLabel = uilabel(app.VIN_CH1);
            app.VIN_CH1_PhaseLabel.Text = 'Phase (DEG)';
            app.VIN_CH1_Phase = uispinner(app.VIN_CH1);
            app.VIN_CH1_Phase.ValueChangingFcn = createCallbackFcn(app, @VIN_CH1_PhaseValueChanging, true);
            app.VIN_CH1_Phase.Limits = [0 360];
            app.VIN_CH1_Phase.ValueDisplayFormat = '%.3f';
            app.VIN_CH1_ButtonOutput = uiswitch(app.VIN_CH1, 'toggle');
            app.VIN_CH1_ButtonOutput.Items = {'CH1 Off', 'CH1 On '};
            app.VIN_CH1_ButtonOutput.ValueChangedFcn = createCallbackFcn(app, @VIN_CH1_ButtonOutputValueChanged, true);
            app.VIN_CH1_ButtonOutput.Value = 'CH1 Off';
            app.VIN_AlignPhase = uibutton(app.VIN, 'push');
            app.VIN_AlignPhase.ButtonPushedFcn = createCallbackFcn(app, @VIN_AlignPhaseButtonPushed, true);
            app.VIN_AlignPhase.Text = 'AlignPhase';
            app.VIN_Store = uibutton(app.VIN, 'push');
            app.VIN_Store.ButtonPushedFcn = createCallbackFcn(app, @VIN_StoreButtonPushed, true);
            app.VIN_Store.Text = 'STORE';
            app.VIN_SR_Channel = uidropdown(app.VIN);
            app.VIN_SR_Channel.Items = {'1', '2', '3', '4'};
            app.VIN_SR_Channel.Value = '1';
            app.VIN_Recall = uibutton(app.VIN, 'push');
            app.VIN_Recall.ButtonPushedFcn = createCallbackFcn(app, @VIN_RecallButtonPushed, true);
            app.VIN_Recall.Text = 'RECALL';
            app.VIN_CH2 = uipanel(app.VIN);
            app.VIN_CH1_Waveform_2Label = uilabel(app.VIN_CH2);
            app.VIN_CH1_Waveform_2Label.Text = 'Waveform';
            app.VIN_CH2_Waveform = uidropdown(app.VIN_CH2);
            app.VIN_CH2_Waveform.Items = {'Sine', 'Square', 'Triangle', 'DC'};
            app.VIN_CH2_Waveform.ValueChangedFcn = createCallbackFcn(app, @VIN_CH2_WaveformValueChanged, true);
            app.VIN_CH2_Waveform.Value = 'Sine';
            app.VIN_CH1_Frequency_2Label = uilabel(app.VIN_CH2);
            app.VIN_CH1_Frequency_2Label.Text = 'Frequency';
            app.VIN_CH2_Frequency = uispinner(app.VIN_CH2);
            app.VIN_CH2_Frequency.ValueChangedFcn = createCallbackFcn(app, @VIN_CH2_FrequencyValueChanging, true);
            app.VIN_CH2_Frequency.Limits = [0 1000];
            app.VIN_CH2_Frequency.ValueDisplayFormat = '%11.8g';
            app.VIN_CH2_Frequency.Value = 1;
            app.VIN_CH2_FrequencyUnit = uidropdown(app.VIN_CH2);
            app.VIN_CH2_FrequencyUnit.Items = {'uHz', 'mHz', 'Hz', 'kHz', 'MHz'};
            app.VIN_CH2_FrequencyUnit.ValueChangedFcn = createCallbackFcn(app, @VIN_CH2_FrequencyUnitValueChanged, true);
            app.VIN_CH2_FrequencyUnit.Value = 'Hz';
            app.VIN_CH1_Amplitude_2Label = uilabel(app.VIN_CH2);
            app.VIN_CH1_Amplitude_2Label.Text = 'Amplitude (V)';
            app.VIN_CH2_Amplitude = uispinner(app.VIN_CH2);
            app.VIN_CH2_Amplitude.Step = 0.1;
            app.VIN_CH2_Amplitude.ValueChangingFcn = createCallbackFcn(app, @VIN_CH2_AmplitudeValueChanging, true);
            app.VIN_CH2_Amplitude.Limits = [0.001 10];
            app.VIN_CH2_Amplitude.ValueDisplayFormat = '%.3f';
            app.VIN_CH2_Amplitude.Value = 1;
            app.VIN_CH1_Offset_2Label = uilabel(app.VIN_CH2);
            app.VIN_CH1_Offset_2Label.Text = 'Offset (V)';
            app.VIN_CH2_Offset = uispinner(app.VIN_CH2);
            app.VIN_CH2_Offset.Step = 0.1;
            app.VIN_CH2_Offset.ValueChangingFcn = createCallbackFcn(app, @VIN_CH2_OffsetValueChanging, true);
            app.VIN_CH2_Offset.Limits = [-5 5];
            app.VIN_CH2_Offset.ValueDisplayFormat = '%.3f';
            app.VIN_CH1_Phase_2Label = uilabel(app.VIN_CH2);
            app.VIN_CH1_Phase_2Label.Text = 'Phase (DEG)';
            app.VIN_CH2_Phase = uispinner(app.VIN_CH2);
            app.VIN_CH2_Phase.ValueChangingFcn = createCallbackFcn(app, @VIN_CH2_PhaseValueChanging, true);
            app.VIN_CH2_Phase.Limits = [0 360];
            app.VIN_CH2_Phase.ValueDisplayFormat = '%.3f';
            app.VIN_CH2_ButtonOutput = uiswitch(app.VIN_CH2, 'toggle');
            app.VIN_CH2_ButtonOutput.Items = {'CH2 Off', 'CH2 On '};
            app.VIN_CH2_ButtonOutput.ValueChangedFcn = createCallbackFcn(app, @VIN_CH2_ButtonOutputValueChanged, true);
            app.VIN_CH2_ButtonOutput.Value = 'CH2 Off';
            app.logo = uibutton(app.EquipmentInterfaceUIFigure, 'push');
            app.logo.ButtonPushedFcn = createCallbackFcn(app, @logoButtonPushed, true);
            app.logo.Icon = 'MCCI icon.png';
            app.logo.Text = '';
            app.PSU2 = uipanel(app.EquipmentInterfaceUIFigure);
            app.PSU2.Title = '~~~~~~~~~~~~~~ PSU #2 ~~~~~~~~~~~~~';
            app.PSU_LedCH5 = uilamp(app.PSU2);
            app.PSU_LedCH5.Enable = 'off';
            app.PSU_LedCH6 = uilamp(app.PSU2);
            app.PSU_LedCH6.Enable = 'off';
            app.PSU_LedCH7 = uilamp(app.PSU2);
            app.PSU_LedCH7.Enable = 'off';
            app.PSU_LedCH8 = uilamp(app.PSU2);
            app.PSU_LedCH8.Enable = 'off';
            app.PSU_ButtonCH5 = uibutton(app.PSU2, 'push');
            app.PSU_ButtonCH5.ButtonPushedFcn = createCallbackFcn(app, @PSU_ButtonCH5ButtonPushed, true);
            app.PSU_ButtonCH5.Text = 'CH5';
            app.PSU_ButtonCH6 = uibutton(app.PSU2, 'push');
            app.PSU_ButtonCH6.ButtonPushedFcn = createCallbackFcn(app, @PSU_ButtonCH6ButtonPushed, true);
            app.PSU_ButtonCH6.Text = 'CH6';
            app.PSU_ButtonCH7 = uibutton(app.PSU2, 'push');
            app.PSU_ButtonCH7.ButtonPushedFcn = createCallbackFcn(app, @PSU_ButtonCH7ButtonPushed, true);
            app.PSU_ButtonCH7.Text = 'CH7';
            app.PSU_ButtonCH8 = uibutton(app.PSU2, 'push');
            app.PSU_ButtonCH8.ButtonPushedFcn = createCallbackFcn(app, @PSU_ButtonCH8ButtonPushed, true);
            app.PSU_ButtonCH8.Text = 'CH8';
            app.PSU_Voltage1_2Label = uilabel(app.PSU2);
            app.PSU_Voltage1_2Label.Text = 'Voltage (V)';
            app.PSU_Voltage5 = uispinner(app.PSU2);
            app.PSU_Voltage5.Step = 0.1;
            app.PSU_Voltage5.ValueChangingFcn = createCallbackFcn(app, @PSU_Voltage5ValueChanging, true);
            app.PSU_Voltage5.Limits = [0 32];
            app.PSU_Voltage5.ValueDisplayFormat = '%.3f';
            app.PSU_Voltage5.Value = 1;
            app.PSU_Current1_2Label = uilabel(app.PSU2);
            app.PSU_Current1_2Label.Text = 'Current (mA)';
            app.PSU_Current5 = uispinner(app.PSU2);
            app.PSU_Current5.ValueChangingFcn = createCallbackFcn(app, @PSU_Current5ValueChanging, true);
            app.PSU_Current5.Limits = [0 10000];
            app.PSU_Current5.ValueDisplayFormat = '%.1f';
            app.PSU_Current5.Value = 1;
            app.PSU_Voltage6 = uispinner(app.PSU2);
            app.PSU_Voltage6.Step = 0.1;
            app.PSU_Voltage6.ValueChangingFcn = createCallbackFcn(app, @PSU_Voltage6ValueChanging, true);
            app.PSU_Voltage6.Limits = [0 32];
            app.PSU_Voltage6.ValueDisplayFormat = '%.3f';
            app.PSU_Voltage6.Value = 1;
            app.PSU_Current6 = uispinner(app.PSU2);
            app.PSU_Current6.ValueChangingFcn = createCallbackFcn(app, @PSU_Current6ValueChanging, true);
            app.PSU_Current6.Limits = [0 10000];
            app.PSU_Current6.ValueDisplayFormat = '%.1f';
            app.PSU_Current6.Value = 1;
            app.PSU_Voltage7 = uispinner(app.PSU2);
            app.PSU_Voltage7.Step = 0.1;
            app.PSU_Voltage7.ValueChangingFcn = createCallbackFcn(app, @PSU_Voltage7ValueChanging, true);
            app.PSU_Voltage7.Limits = [0 32];
            app.PSU_Voltage7.ValueDisplayFormat = '%.3f';
            app.PSU_Voltage7.Value = 1;
            app.PSU_Current7 = uispinner(app.PSU2);
            app.PSU_Current7.ValueChangingFcn = createCallbackFcn(app, @PSU_Current7ValueChanging, true);
            app.PSU_Current7.Limits = [0 10000];
            app.PSU_Current7.ValueDisplayFormat = '%.1f';
            app.PSU_Current7.Value = 1;
            app.PSU_Voltage8 = uispinner(app.PSU2);
            app.PSU_Voltage8.Step = 0.1;
            app.PSU_Voltage8.ValueChangingFcn = createCallbackFcn(app, @PSU_Voltage8ValueChanging, true);
            app.PSU_Voltage8.Limits = [0 32];
            app.PSU_Voltage8.ValueDisplayFormat = '%.3f';
            app.PSU_Voltage8.Value = 1;
            app.PSU_Current8 = uispinner(app.PSU2);
            app.PSU_Current8.ValueChangingFcn = createCallbackFcn(app, @PSU_Current8ValueChanging, true);
            app.PSU_Current8.Limits = [0 10000];
            app.PSU_Current8.ValueDisplayFormat = '%.1f';
            app.PSU_Current8.Value = 1;
            app.PSU_ButtonOutput_psu2 = uibutton(app.PSU2, 'push');
            app.PSU_ButtonOutput_psu2.ButtonPushedFcn = createCallbackFcn(app, @PSU_ButtonOutput2ButtonPushed, true);
            app.PSU_ButtonOutput_psu2.Text = 'OUTPUT';
            app.PSU_LedOutput_psu2 = uilamp(app.PSU2);
            app.PSU_LedOutput_psu2.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = launch_GUI_instrument()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EquipmentInterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EquipmentInterfaceUIFigure)
        end
    end
end